classdef nMNSD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TriangleChartPanel          matlab.ui.container.Panel
        UIAxes_1                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_5                    matlab.ui.control.UIAxes
        UIAxes_6                    matlab.ui.control.UIAxes
        UIAxes_7                    matlab.ui.control.UIAxes
        UIAxes_8                    matlab.ui.control.UIAxes
        UIAxes_9                    matlab.ui.control.UIAxes
        UIAxes_10                   matlab.ui.control.UIAxes
        Label_Leg                   matlab.ui.control.Label
        ESLabel_1                   matlab.ui.control.Label
        ESLabel_2                   matlab.ui.control.Label
        ESLabel_3                   matlab.ui.control.Label
        ESLabel_4                   matlab.ui.control.Label
        ESLabel_5                   matlab.ui.control.Label
        ESLabel_6                   matlab.ui.control.Label
        ESLabel_7                   matlab.ui.control.Label
        ESLabel_8                   matlab.ui.control.Label
        ESLabel_9                   matlab.ui.control.Label
        ESLabel_10                  matlab.ui.control.Label
        Lamp1                       matlab.ui.control.Lamp
        Lamp2                       matlab.ui.control.Lamp
        Lamp3                       matlab.ui.control.Lamp
        Lamp4                       matlab.ui.control.Lamp
        Lamp5                       matlab.ui.control.Lamp
        Lamp6                       matlab.ui.control.Lamp
        Lamp7                       matlab.ui.control.Lamp
        Lamp8                       matlab.ui.control.Lamp
        Lamp9                       matlab.ui.control.Lamp
        Lamp10                      matlab.ui.control.Lamp
        CurrentS_pLabel             matlab.ui.control.Label
        CurrentS_pEditField         matlab.ui.control.NumericEditField
        StructureparametersPanel    matlab.ui.container.Panel
        wD1ES1EditFieldLabel        matlab.ui.control.Label
        wD1ES1EditField             matlab.ui.control.NumericEditField
        wD2ES2EditFieldLabel        matlab.ui.control.Label
        wD2ES2EditField             matlab.ui.control.NumericEditField
        wD3ES3EditFieldLabel        matlab.ui.control.Label
        wD3ES3EditField             matlab.ui.control.NumericEditField
        wD4ES4EditFieldLabel        matlab.ui.control.Label
        wD4ES4EditField             matlab.ui.control.NumericEditField
        wD5ES5EditFieldLabel        matlab.ui.control.Label
        wD5ES5EditField             matlab.ui.control.NumericEditField
        wD6ES6EditFieldLabel        matlab.ui.control.Label
        wD6ES6EditField             matlab.ui.control.NumericEditField
        wD7ES7EditFieldLabel        matlab.ui.control.Label
        wD7ES7EditField             matlab.ui.control.NumericEditField
        wD8ES8EditFieldLabel        matlab.ui.control.Label
        wD8ES8EditField             matlab.ui.control.NumericEditField
        wD9ES9EditFieldLabel        matlab.ui.control.Label
        wD9ES9EditField             matlab.ui.control.NumericEditField
        wD10ES10EditFieldLabel      matlab.ui.control.Label
        wD10ES10EditField           matlab.ui.control.NumericEditField
        S_thEditFieldLabel          matlab.ui.control.Label
        S_thEditField               matlab.ui.control.NumericEditField
        L_dEditFieldLabel           matlab.ui.control.Label
        L_dEditField                matlab.ui.control.NumericEditField
        wT1D1EditFieldLabel         matlab.ui.control.Label
        wT1D1EditField              matlab.ui.control.NumericEditField
        wT2D2EditFieldLabel         matlab.ui.control.Label
        wT2D2EditField              matlab.ui.control.NumericEditField
        wT3D3EditFieldLabel         matlab.ui.control.Label
        wT3D3EditField              matlab.ui.control.NumericEditField
        wT4D4EditFieldLabel         matlab.ui.control.Label
        wT4D4EditField              matlab.ui.control.NumericEditField
        wT5D5EditFieldLabel         matlab.ui.control.Label
        wT5D5EditField              matlab.ui.control.NumericEditField
        wT6D6EditFieldLabel         matlab.ui.control.Label
        wT6D6EditField              matlab.ui.control.NumericEditField
        wT7D7EditFieldLabel         matlab.ui.control.Label
        wT7D7EditField              matlab.ui.control.NumericEditField
        wT8D8EditFieldLabel         matlab.ui.control.Label
        wT8D8EditField              matlab.ui.control.NumericEditField
        wT9D9EditFieldLabel         matlab.ui.control.Label
        wT9D9EditField              matlab.ui.control.NumericEditField
        wT10D10EditFieldLabel       matlab.ui.control.Label
        wT10D10EditField            matlab.ui.control.NumericEditField
        TargetsummationPanel        matlab.ui.container.Panel
        SpikegentimeEditFieldLabel  matlab.ui.control.Label
        SpikegentimeEditField       matlab.ui.control.NumericEditField
        S_pMEditFieldLabel          matlab.ui.control.Label
        S_pMEditField               matlab.ui.control.NumericEditField
        UIAxes                      matlab.ui.control.UIAxes
        Label_Leg_2                 matlab.ui.control.Label
        LoadingandcontrolPanel      matlab.ui.container.Panel
        Panel                       matlab.ui.container.Panel
        LoadnMNSDparametersButton   matlab.ui.control.StateButton
        ResetinputweightsButton     matlab.ui.control.StateButton
        Panel_2                     matlab.ui.container.Panel
        LoadthesetofparallelspiketrainsButton  matlab.ui.control.StateButton
        TrainnSpinnerLabel          matlab.ui.control.Label
        TrainnSpinner               matlab.ui.control.Spinner
        StepnSpinnerLabel           matlab.ui.control.Label
        StepnSpinner                matlab.ui.control.Spinner
    end

    
    properties (Access = public) %  Qui metto le variabili che voglio usare in altre sezioni delle App, che pero non siano gia definite dalle App stesse (quelle sono automaticamente dichiarate public)
        PPT_abs 
        i_refs
        S_th
        t_ES
        w_D
        w_D_reset=[1.08;1.08;1.08;1.08;1.08;1.08;1.08;1.08;1.08;1.08]
        w_T
        Current_S_p
        tri_off
        base_rect
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TrainnSpinner
        function TrainnSpinnerValueChanged(app, event)
      %% Initialization (Choose single pES)
      app.TrainnSpinner.Limits = [1, size(app.t_ES,2)]
            value = app.TrainnSpinner.Value;  
            
      %% Resetta contatore step
            app.StepnSpinner.Value=0;
            
      %% Resetta gli i_ref notifiers
            app.Lamp1.Enable = 'off';
            app.Lamp2.Enable = 'off';
            app.Lamp3.Enable = 'off';
            app.Lamp4.Enable = 'off';
            app.Lamp5.Enable = 'off';
            app.Lamp6.Enable = 'off';
            app.Lamp7.Enable = 'off';
            app.Lamp8.Enable = 'off';
            app.Lamp9.Enable = 'off';
            app.Lamp10.Enable = 'off';
            
      %% Resetta gli assi
            cla(app.UIAxes_1);
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)
            cla(app.UIAxes_4)
            cla(app.UIAxes_5)
            cla(app.UIAxes_6)
            cla(app.UIAxes_7)
            cla(app.UIAxes_8)
            cla(app.UIAxes_9)
            cla(app.UIAxes_10);
            
      %% Sbianca il grafico a destra
            cla(app.UIAxes)
            
      %% Sbianca casella S_p
            app.CurrentS_pEditField.Value=0;
            
      %% triangle_offset computation x visualization alignment (uno per ogni i_ref)
            t_ES_ref(:,1) = app.t_ES(app.i_refs (:,value),value);  % time dell'ES_ref del trial corrente
            app.tri_off(:,1) = app.PPT_abs(app.i_refs (:,value),1) - t_ES_ref(:); %sets of triangle offsets del trial corrente per fare in modo che nella visualizzazione combaci con l'ES del i_ref


        end

        % Value changed function: LoadnMNSDparametersButton
        function LoadnMNSDparametersButtonValueChanged(app, event)
        %value = app.LoadnMNSDparametersButton.Value;
        

        %% Resetta contatore train n.
            app.TrainnSpinner.Value=0;
        
        %% Resetta contatore step
            app.StepnSpinner.Value=0;
            
        %% Carico parametri static struttura
            %load('nMNSD_pack.mat', 'L_d', 'w_D', 'w_T', 'S_th', 'A', 'tau'); %Carico struttura
            uiopen; figure(app.UIFigure);
            
            app.L_dEditField.Value = L_d;
            app.S_thEditField.Value = S_th;
            %app.tauEditField.Value = tau;
            %app.AEditField.Value = A;
            app.w_D=w_D;  %% ridondante, ma serve x far funzionare la target summation
            app.w_T=w_T;
            
        %% "Azzera" i valori per cui la struttura non esiste (ES < 10)
            if size(w_D,1)>=1 app.wD1ES1EditField.Value=w_D(1,1); else app.wD1ES1EditField.Value=0; end 
            if size(w_D,1)>=2 app.wD2ES2EditField.Value=w_D(2,1); else app.wD2ES2EditField.Value=0; end 
            if size(w_D,1)>=3 app.wD3ES3EditField.Value=w_D(3,1); else app.wD3ES3EditField.Value=0; end
            if size(w_D,1)>=4 app.wD4ES4EditField.Value=w_D(4,1); else app.wD4ES4EditField.Value=0; end
            if size(w_D,1)>=5 app.wD5ES5EditField.Value=w_D(5,1); else app.wD5ES5EditField.Value=0; end
            if size(w_D,1)>=6 app.wD6ES6EditField.Value=w_D(6,1); else app.wD6ES6EditField.Value=0; end
            if size(w_D,1)>=7 app.wD7ES7EditField.Value=w_D(7,1); else app.wD7ES7EditField.Value=0; end
            if size(w_D,1)>=8 app.wD8ES8EditField.Value=w_D(8,1); else app.wD8ES8EditField.Value=0; end
            if size(w_D,1)>=9 app.wD9ES9EditField.Value=w_D(9,1); else app.wD9ES9EditField.Value=0; end
            if size(w_D,1)>=10 app.wD10ES10EditField.Value=w_D(10,1); else app.wD10ES10EditField.Value=0; end
            
            
            if size(w_T,1)>=1 app.wT1D1EditField.Value=w_T(1,1); else app.wT1D1EditField.Value=0; end 
            if size(w_T,1)>=2 app.wT2D2EditField.Value=w_T(2,1); else app.wT2D2EditField.Value=0; end
            if size(w_T,1)>=3 app.wT3D3EditField.Value=w_T(3,1); else app.wT3D3EditField.Value=0; end
            if size(w_T,1)>=4 app.wT4D4EditField.Value=w_T(4,1); else app.wT4D4EditField.Value=0; end
            if size(w_T,1)>=5 app.wT5D5EditField.Value=w_T(5,1); else app.wT5D5EditField.Value=0; end
            if size(w_T,1)>=6 app.wT6D6EditField.Value=w_T(6,1); else app.wT6D6EditField.Value=0; end
            if size(w_T,1)>=7 app.wT7D7EditField.Value=w_T(7,1); else app.wT7D7EditField.Value=0; end
            if size(w_T,1)>=8 app.wT8D8EditField.Value=w_T(8,1); else app.wT8D8EditField.Value=0; end
            if size(w_T,1)>=9 app.wT9D9EditField.Value=w_T(9,1); else app.wT9D9EditField.Value=0; end
            if size(w_T,1)>=10 app.wT10D10EditField.Value=w_T(10,1); else app.wT10D10EditField.Value=0; end
            
            app.LoadnMNSDparametersButton.Value = 0;
            
         %% Trova centri triangoli per motivi di visualizzazione (ricorda che in realta i loro delta sono certi, mentre il loro val assoluto non andrebbe fissato)
            app.PPT_abs = zeros(size (app.w_D,1),1);
            for ii = 1:size (app.w_D,1) 
            app.PPT_abs(ii,1)=-1/(app.w_D(ii,1)-1);
            end
            
        end

        % Value changed function: 
        % LoadthesetofparallelspiketrainsButton
        function LoadthesetofparallelspiketrainsButtonValueChanged(app, event)
            %value = app.LoadthesetofparallelspiketrainsButton.Value;

        %% Resetta gli i_ref notifiers
            app.Lamp1.Enable = 'off';
            app.Lamp2.Enable = 'off';
            app.Lamp3.Enable = 'off';
            app.Lamp4.Enable = 'off';
            app.Lamp5.Enable = 'off';
            app.Lamp6.Enable = 'off';
            app.Lamp7.Enable = 'off';
            app.Lamp8.Enable = 'off';
            app.Lamp9.Enable = 'off';
            app.Lamp10.Enable = 'off';
            
        %% Resetta contatore train n.
            app.TrainnSpinner.Value=0;
        
        %% Resetta contatore step
            app.StepnSpinner.Value=0;  
            
        %% Sbianca i grafici a sinistra
            cla(app.UIAxes_1)
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)
            cla(app.UIAxes_4)
            cla(app.UIAxes_5)
            cla(app.UIAxes_6)
            cla(app.UIAxes_7)
            cla(app.UIAxes_8)
            cla(app.UIAxes_9)
            cla(app.UIAxes_10)
            
        %% Sbianca il grafico a destra
        
            cla(app.UIAxes)
            
         %% Load ESs   
            % load('nMNSD_pack.mat', 't_ES');  %Carico la sequenza di pES
            uiopen; figure (app.UIFigure);
            
            app.t_ES=t_ES;
            
            app.i_refs = zeros(size(app.t_ES,1),size(app.t_ES,2)); %cerco un set di i_refs per ogni trial (uno per ogni nuovo single ES)

            for ii = 1:size(app.t_ES,2)
             [~, i_seq]= sort(app.PPT_abs (:,1)- app.t_ES(:,ii), 'descend');
             app.i_refs (:,ii)=i_seq;
            end
                 
           
            app.LoadthesetofparallelspiketrainsButton.Value = 0;
            
            
        %%  Calcolo lunghezza rettangoli
        
        app.base_rect = zeros (size(app.t_ES,1), size(app.t_ES,2));
        app.base_rect(app.i_refs(1,:),:) = 0;
        
          
        for jj = 1:1:size(app.t_ES,2)
            
         if app.PPT_abs(app.i_refs(1,jj)) - app.PPT_abs(app.i_refs(2,jj)) + app.t_ES(app.i_refs(2,jj),jj) - app.t_ES(app.i_refs(1,jj),jj) > (app.w_T(app.i_refs(1,jj))/app.L_dEditField.Value) + app.base_rect(app.i_refs(1,jj),jj) 
           app.base_rect(app.i_refs(2,jj),jj) = 0 ; 
         else 
            app.base_rect(app.i_refs(2,jj),jj) =  (app.w_T(app.i_refs(1,jj))/app.L_dEditField.Value)- (app.PPT_abs(app.i_refs(1,jj)) - app.PPT_abs(app.i_refs(2,jj)) + app.t_ES(app.i_refs(2,jj),jj) - app.t_ES(app.i_refs(1,jj),jj)  - app.base_rect(app.i_refs(1,jj),jj) );
         end
                  
         if app.PPT_abs(app.i_refs(2,jj)) - app.PPT_abs(app.i_refs(3,jj)) + app.t_ES(app.i_refs(3,jj),jj) - app.t_ES(app.i_refs(2,jj),jj) > (app.w_T(app.i_refs(2,jj))/app.L_dEditField.Value) + app.base_rect(app.i_refs(2,jj),jj) 
           app.base_rect(app.i_refs(3,jj),jj) = 0; 
         else 
            app.base_rect(app.i_refs(3,jj),jj) =  (app.w_T(app.i_refs(2,jj))/app.L_dEditField.Value)- (app.PPT_abs(app.i_refs(2,jj)) - app.PPT_abs(app.i_refs(3,jj)) + app.t_ES(app.i_refs(3,jj),jj) - app.t_ES(app.i_refs(2,jj),jj)  - app.base_rect(app.i_refs(2,jj),jj) );
         end
         
         if app.PPT_abs(app.i_refs(3,jj)) - app.PPT_abs(app.i_refs(4,jj)) + app.t_ES(app.i_refs(4,jj),jj) - app.t_ES(app.i_refs(3,jj),jj) > (app.w_T(app.i_refs(3,jj))/app.L_dEditField.Value) + app.base_rect(app.i_refs(3,jj),jj) 
           app.base_rect(app.i_refs(4,jj),jj) = 0; 
         else 
            app.base_rect(app.i_refs(4,jj),jj) =  (app.w_T(app.i_refs(3,jj))/app.L_dEditField.Value)- (app.PPT_abs(app.i_refs(3,jj)) - app.PPT_abs(app.i_refs(4,jj)) + app.t_ES(app.i_refs(4,jj),jj) - app.t_ES(app.i_refs(3,jj),jj)  - app.base_rect(app.i_refs(3,jj),jj) );
         end
                  
         if app.PPT_abs(app.i_refs(4,jj)) - app.PPT_abs(app.i_refs(5,jj)) + app.t_ES(app.i_refs(5,jj),jj) - app.t_ES(app.i_refs(4,jj),jj) > (app.w_T(app.i_refs(4,jj))/app.L_dEditField.Value) + app.base_rect(app.i_refs(4,jj),jj) 
           app.base_rect(app.i_refs(5,jj),jj) = 0; 
         else 
            app.base_rect(app.i_refs(5,jj),jj) =  (app.w_T(app.i_refs(4,jj))/app.L_dEditField.Value)- (app.PPT_abs(app.i_refs(4,jj)) - app.PPT_abs(app.i_refs(5,jj)) + app.t_ES(app.i_refs(5,jj),jj) - app.t_ES(app.i_refs(4,jj),jj)  - app.base_rect(app.i_refs(4,jj),jj) );
         end
                           
         if app.PPT_abs(app.i_refs(5,jj)) - app.PPT_abs(app.i_refs(6,jj)) + app.t_ES(app.i_refs(6,jj),jj) - app.t_ES(app.i_refs(5,jj),jj) > (app.w_T(app.i_refs(5,jj))/app.L_dEditField.Value) + app.base_rect(app.i_refs(5,jj),jj) 
           app.base_rect(app.i_refs(6,jj),jj) = 0; 
         else 
            app.base_rect(app.i_refs(6,jj),jj) =  (app.w_T(app.i_refs(5,jj))/app.L_dEditField.Value)- (app.PPT_abs(app.i_refs(5,jj)) - app.PPT_abs(app.i_refs(6,jj)) + app.t_ES(app.i_refs(6,jj),jj) - app.t_ES(app.i_refs(5,jj),jj)  - app.base_rect(app.i_refs(5,jj),jj) );
         end
                                    
         if app.PPT_abs(app.i_refs(6,jj)) - app.PPT_abs(app.i_refs(7,jj)) + app.t_ES(app.i_refs(7,jj),jj) - app.t_ES(app.i_refs(6,jj),jj) > (app.w_T(app.i_refs(6,jj))/app.L_dEditField.Value) + app.base_rect(app.i_refs(6,jj),jj) 
           app.base_rect(app.i_refs(7,jj),jj) = 0; 
         else 
            app.base_rect(app.i_refs(7,jj),jj) =  (app.w_T(app.i_refs(6,jj))/app.L_dEditField.Value)- (app.PPT_abs(app.i_refs(6,jj)) - app.PPT_abs(app.i_refs(7,jj)) + app.t_ES(app.i_refs(7,jj),jj) - app.t_ES(app.i_refs(6,jj),jj)  - app.base_rect(app.i_refs(6,jj),jj) );
         end
                                             
         if app.PPT_abs(app.i_refs(7,jj)) - app.PPT_abs(app.i_refs(8,jj)) + app.t_ES(app.i_refs(8,jj),jj) - app.t_ES(app.i_refs(7,jj),jj) > (app.w_T(app.i_refs(7,jj))/app.L_dEditField.Value) + app.base_rect(app.i_refs(7,jj),jj) 
           app.base_rect(app.i_refs(8,jj),jj) = 0; 
         else 
            app.base_rect(app.i_refs(8,jj),jj) =  (app.w_T(app.i_refs(7,jj))/app.L_dEditField.Value)- (app.PPT_abs(app.i_refs(7,jj)) - app.PPT_abs(app.i_refs(8,jj)) + app.t_ES(app.i_refs(8,jj),jj) - app.t_ES(app.i_refs(7,jj),jj)  - app.base_rect(app.i_refs(7,jj),jj) );
         end
                                                      
         if app.PPT_abs(app.i_refs(8,jj)) - app.PPT_abs(app.i_refs(9,jj)) + app.t_ES(app.i_refs(9,jj),jj) - app.t_ES(app.i_refs(8,jj),jj) > (app.w_T(app.i_refs(8,jj))/app.L_dEditField.Value) + app.base_rect(app.i_refs(8,jj),jj) 
           app.base_rect(app.i_refs(9,jj),jj) = 0; 
         else 
            app.base_rect(app.i_refs(9,jj),jj) =  (app.w_T(app.i_refs(8,jj))/app.L_dEditField.Value)- (app.PPT_abs(app.i_refs(8,jj)) - app.PPT_abs(app.i_refs(9,jj)) + app.t_ES(app.i_refs(9,jj),jj) - app.t_ES(app.i_refs(8,jj),jj)  - app.base_rect(app.i_refs(8,jj),jj) );
         end
                                                               
         if app.PPT_abs(app.i_refs(9,jj)) - app.PPT_abs(app.i_refs(10,jj)) + app.t_ES(app.i_refs(10,jj),jj) - app.t_ES(app.i_refs(9,jj),jj) > (app.w_T(app.i_refs(9,jj))/app.L_dEditField.Value) + app.base_rect(app.i_refs(9,jj),jj) 
           app.base_rect(app.i_refs(10,jj),jj) = 0; 
         else 
            app.base_rect(app.i_refs(10,jj),jj) =  (app.w_T(app.i_refs(9,jj))/app.L_dEditField.Value)- (app.PPT_abs(app.i_refs(9,jj)) - app.PPT_abs(app.i_refs(10,jj)) + app.t_ES(app.i_refs(10,jj),jj) - app.t_ES(app.i_refs(9,jj),jj) - app.base_rect(app.i_refs(9,jj),jj) );
         end
         
        end


        end

        % Value changed function: ResetinputweightsButton
        function ResetinputweightsButtonValueChanged(app, event)
% 
%             
%             % value = app.ResetinputweightsButton.Value;
%             
%             app.wD1ES1EditField.Value=app.w_D_reset(1,1);
%             app.wD2ES2EditField.Value=app.w_D_reset(2,1); 
%             app.wD3ES3EditField.Value=app.w_D_reset(3,1);
%             app.wD4ES4EditField.Value=app.w_D_reset(4,1); 
%             app.wD5ES5EditField.Value=app.w_D_reset(5,1);
%             app.wD6ES6EditField.Value=app.w_D_reset(6,1); 
%             app.wD7ES7EditField.Value=app.w_D_reset(7,1);
%             app.wD8ES8EditField.Value=app.w_D_reset(8,1); 
%             app.wD9ES9EditField.Value=app.w_D_reset(9,1);
%             app.wD10ES10EditField.Value=app.w_D_reset(10,1); 
%             
%             
%             app.LoadnMNSDparametersButton.Value = 0;
%             
%             
%          %% Trova ascissa retta dei trapezi per motivi di visualizzazione (ricorda che in realta i loro delta sono certi, mentre il loro val assoluto non andrebbe fissato)
%             app.PPT_abs = zeros(size (app.w_D_reset,1),1);
%             for ii = 1:size (app.w_D_reset,1) 
%             app.PPT_abs(ii,1)=-1/(app.w_D_reset(ii,1)-1);
%             end
%             
%             app.ResetinputweightsButton.Value = 0;
%             
%             
%             app.LoadthesetofparallelspiketrainsButtonValueChanged
%             
        end

        % Value changed function: StepnSpinner
        function StepnSpinnerValueChanged(app, event)
            %value = app.StepnSpinner.Value;
            app.StepnSpinner.Limits = [0, size(app.t_ES,1)]
            
         %% Resetta gli i_ref notifiers e poi riaccendili
            app.Lamp1.Enable = 'off';
            app.Lamp2.Enable = 'off';
            app.Lamp3.Enable = 'off';
            app.Lamp4.Enable = 'off';
            app.Lamp5.Enable = 'off';
            app.Lamp6.Enable = 'off';
            app.Lamp7.Enable = 'off';
            app.Lamp8.Enable = 'off';
            app.Lamp9.Enable = 'off';
            app.Lamp10.Enable = 'off';
      
            if (app.i_refs(app.StepnSpinner.Value,app.TrainnSpinner.Value)) ==1
            app.Lamp1.Enable = 'on';
            elseif (app.i_refs(app.StepnSpinner.Value,app.TrainnSpinner.Value)) ==2
            app.Lamp2.Enable = 'on';
            elseif (app.i_refs(app.StepnSpinner.Value,app.TrainnSpinner.Value)) ==3
            app.Lamp3.Enable = 'on';
            elseif (app.i_refs(app.StepnSpinner.Value,app.TrainnSpinner.Value)) ==4
            app.Lamp4.Enable = 'on';
            elseif (app.i_refs(app.StepnSpinner.Value,app.TrainnSpinner.Value)) ==5
            app.Lamp5.Enable = 'on';
            elseif (app.i_refs(app.StepnSpinner.Value,app.TrainnSpinner.Value)) ==6
            app.Lamp6.Enable = 'on';
            elseif (app.i_refs(app.StepnSpinner.Value,app.TrainnSpinner.Value)) ==7
            app.Lamp7.Enable = 'on';
            elseif (app.i_refs(app.StepnSpinner.Value,app.TrainnSpinner.Value)) ==8
            app.Lamp8.Enable = 'on';
            elseif (app.i_refs(app.StepnSpinner.Value,app.TrainnSpinner.Value)) ==9
            app.Lamp9.Enable = 'on';
            elseif (app.i_refs(app.StepnSpinner.Value,app.TrainnSpinner.Value)) ==10
            app.Lamp10.Enable = 'on';
            end


      %% Axis
         %% 1
            cla(app.UIAxes_1)

            
            x_tri(app.TrainnSpinner.Value, :) = [(app.PPT_abs(1,1)-app.wT1D1EditField.Value/app.L_dEditField.Value)-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(1,app.TrainnSpinner.Value), (app.PPT_abs(1,1))-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(1,app.TrainnSpinner.Value), (app.PPT_abs(1,1))-app.tri_off(app.StepnSpinner.Value,1), (app.PPT_abs(1,1))-app.tri_off(app.StepnSpinner.Value,1)];
            y_tri(app.TrainnSpinner.Value, :) = [0, app.wT1D1EditField.Value, app.wT1D1EditField.Value, 0];
            
             x_inters(1,app.TrainnSpinner.Value) = app.t_ES(1,app.TrainnSpinner.Value); % vediamo qui la x and y intersection (ovvero le e_i)
            if app.t_ES(1,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >= 1e-5 % a sx del rettangolo
                y_inters(1,app.TrainnSpinner.Value)=0;
            elseif and (app.t_ES(1,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)>0 , app.t_ES(1,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3)<0) %nel rettangolo
                y_inters(1,app.TrainnSpinner.Value)=app.wT1D1EditField.Value;
            elseif  and ( app.t_ES(1,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) < 1e-5 , app.t_ES(1,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >=0 ) %se combacia proprio il momento di scatto prendi il vertice (condizione forzata senno a volte non mi segnalava il vertice)
                y_inters(1,app.TrainnSpinner.Value) = app.wT1D1EditField.Value;
            elseif and ( app.t_ES(1,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2) < 0  , abs(app.t_ES(1,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)) - app.wT1D1EditField.Value/app.L_dEditField.Value < 0) % nel triangolo
                y_inters(1,app.TrainnSpinner.Value) = app.wT1D1EditField.Value -app.L_dEditField.Value*(x_tri(app.TrainnSpinner.Value, 2) - app.t_ES(1,app.TrainnSpinner.Value)); 
            else
                y_inters(1,app.TrainnSpinner.Value)=0;
            end
            
            patch(app.UIAxes_1,(x_tri(app.TrainnSpinner.Value,:))',(y_tri(app.TrainnSpinner.Value,:))',[0.8 0.8 0.8], 'EdgeColor',[0.8 0.8 0.8]); % Triangle
            xline(app.UIAxes_1,app.t_ES(1,app.TrainnSpinner.Value),'r','LineWidth',2); % ES
            scatter(app.UIAxes_1,x_inters(1,app.TrainnSpinner.Value),y_inters(1,app.TrainnSpinner.Value),30,'k','o') % Intersection
            X_limit = x_tri(app.TrainnSpinner.Value, 2) + app.base_rect(1,app.TrainnSpinner.Value); 
            app.UIAxes_1.XLim = [-25+X_limit +25+X_limit];
            app.UIAxes_1.YLim = [0 1];

         %% 2
            cla(app.UIAxes_2)

            x_tri(app.TrainnSpinner.Value, :) = [(app.PPT_abs(2,1) - app.wT2D2EditField.Value/app.L_dEditField.Value)-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(2,app.TrainnSpinner.Value), (app.PPT_abs(2,1))-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(2,app.TrainnSpinner.Value), (app.PPT_abs(2,1))-app.tri_off(app.StepnSpinner.Value,1), (app.PPT_abs(2,1))-app.tri_off(app.StepnSpinner.Value,1)];
            y_tri(app.TrainnSpinner.Value, :) = [0, app.wT2D2EditField.Value, app.wT2D2EditField.Value, 0];
            
            x_inters(2,app.TrainnSpinner.Value) = app.t_ES(2,app.TrainnSpinner.Value);
            if app.t_ES(2,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3 ) >= 1e-5 % a sx del rettangolo
                y_inters(2,app.TrainnSpinner.Value)=0;
            elseif and (app.t_ES(2,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)>0 , app.t_ES(2,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3)<0) %nel rettangolo
                y_inters(2,app.TrainnSpinner.Value)=app.wT2D2EditField.Value;
            elseif  and ( app.t_ES(2,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) < 1e-5 , app.t_ES(2,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >=0 ) %se combacia proprio il momento di scatto prendi il vertice (condizione forzata senno a volte non mi segnalava il vertice)
                y_inters(2,app.TrainnSpinner.Value) = app.wT2D2EditField.Value;            
            elseif and ( app.t_ES(2,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2) < 0  , abs(app.t_ES(2,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)) - app.wT2D2EditField.Value/app.L_dEditField.Value < 0) % nel triangolo
                y_inters(2,app.TrainnSpinner.Value) = app.wT2D2EditField.Value -app.L_dEditField.Value*(x_tri(app.TrainnSpinner.Value, 2) - app.t_ES(2,app.TrainnSpinner.Value)); 
            else
                y_inters(2,app.TrainnSpinner.Value)=0;
            end
            
            patch(app.UIAxes_2,(x_tri(app.TrainnSpinner.Value,:))',(y_tri(app.TrainnSpinner.Value,:))',[0.8 0.8 0.8], 'EdgeColor',[0.8 0.8 0.8]); % Triangle
            xline(app.UIAxes_2,app.t_ES(2,app.TrainnSpinner.Value),'g','LineWidth',2); % ES
            scatter(app.UIAxes_2,x_inters(2,app.TrainnSpinner.Value),y_inters(2,app.TrainnSpinner.Value),30,'k','o') % Intersection
            X_limit=x_tri(app.TrainnSpinner.Value, 2) + (app.PPT_abs(1,1) - app.PPT_abs(2,1) + app.base_rect(2,app.TrainnSpinner.Value)); 
            app.UIAxes_2.XLim = [-25+X_limit +25+X_limit];
            app.UIAxes_2.YLim = [0 1];
            
         %% 3  
            cla(app.UIAxes_3)
            app.TrainnSpinner.Value = app.TrainnSpinner.Value;

            x_tri(app.TrainnSpinner.Value, :) = [(app.PPT_abs(3,1) - app.wT3D3EditField.Value/app.L_dEditField.Value)-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(3,app.TrainnSpinner.Value), (app.PPT_abs(3,1))-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(3,app.TrainnSpinner.Value), (app.PPT_abs(3,1))-app.tri_off(app.StepnSpinner.Value,1), (app.PPT_abs(3,1))-app.tri_off(app.StepnSpinner.Value,1)];
            y_tri(app.TrainnSpinner.Value, :) = [0, app.wT3D3EditField.Value, app.wT3D3EditField.Value, 0];
            
            x_inters(3,app.TrainnSpinner.Value) = app.t_ES(3,app.TrainnSpinner.Value);
            if app.t_ES(3,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >= 1e-5 % a sx del rettangolo
                y_inters(3,app.TrainnSpinner.Value)=0;
            elseif and (app.t_ES(3,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)>0 , app.t_ES(3,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3)<0) %nel rettangolo
                y_inters(3,app.TrainnSpinner.Value)=app.wT3D3EditField.Value;
            elseif  and (app.t_ES(3,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) < 1e-5 , app.t_ES(3,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >=0 )%se combacia proprio il momento di scatto prendi il vertice (condizione forzata senno a volte non mi segnalava il vertice)
                y_inters(3,app.TrainnSpinner.Value) = app.wT3D3EditField.Value;            
            elseif and ( app.t_ES(3,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2) < 0  , abs(app.t_ES(3,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)) - app.wT3D3EditField.Value/app.L_dEditField.Value < 0) % nel triangolo
                y_inters(3,app.TrainnSpinner.Value) = app.wT3D3EditField.Value -app.L_dEditField.Value*(x_tri(app.TrainnSpinner.Value, 2) - app.t_ES(3,app.TrainnSpinner.Value)); 
            else
                y_inters(3,app.TrainnSpinner.Value)=0;
            end
            
            patch(app.UIAxes_3,(x_tri(app.TrainnSpinner.Value,:))',(y_tri(app.TrainnSpinner.Value,:))',[0.8 0.8 0.8], 'EdgeColor',[0.8 0.8 0.8]); % Triangle
            xline(app.UIAxes_3,app.t_ES(3,app.TrainnSpinner.Value),'b','LineWidth',2); % ES
            scatter(app.UIAxes_3,x_inters(3,app.TrainnSpinner.Value),y_inters(3,app.TrainnSpinner.Value),30,'k','o') % Intersection 
            X_limit=x_tri(app.TrainnSpinner.Value, 2) + (app.PPT_abs(1,1) - app.PPT_abs(3,1) + app.base_rect(3,app.TrainnSpinner.Value)); 
            app.UIAxes_3.XLim = [-25+X_limit +25+X_limit];
            app.UIAxes_3.YLim = [0 1];
            
         %% 4   
            cla(app.UIAxes_4)
            app.TrainnSpinner.Value = app.TrainnSpinner.Value;

            x_tri(app.TrainnSpinner.Value, :) = [(app.PPT_abs(4,1) - app.wT4D4EditField.Value/app.L_dEditField.Value)-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(4,app.TrainnSpinner.Value), (app.PPT_abs(4,1))-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(4,app.TrainnSpinner.Value), (app.PPT_abs(4,1))-app.tri_off(app.StepnSpinner.Value,1), (app.PPT_abs(4,1))-app.tri_off(app.StepnSpinner.Value,1)];
            y_tri(app.TrainnSpinner.Value, :) = [0, app.wT4D4EditField.Value, app.wT4D4EditField.Value, 0];
            
            x_inters(4,app.TrainnSpinner.Value) = app.t_ES(4,app.TrainnSpinner.Value);
            if app.t_ES(4,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >= 1e-5 % a sx del rettangolo
                y_inters(4,app.TrainnSpinner.Value)=0;
            elseif and (app.t_ES(4,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)>0 , app.t_ES(4,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3)<0) %nel rettangolo
                y_inters(4,app.TrainnSpinner.Value)=app.wT4D4EditField.Value;
            elseif  and ( app.t_ES(4,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) < 1e-5 , app.t_ES(4,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >=0 )%se combacia proprio il momento di scatto prendi il vertice (condizione forzata senno a volte non mi segnalava il vertice)
                y_inters(4,app.TrainnSpinner.Value) = app.wT4D4EditField.Value;            
            elseif and ( app.t_ES(4,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2) < 0  , abs(app.t_ES(4,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)) - app.wT4D4EditField.Value/app.L_dEditField.Value < 0) % nel triangolo
                y_inters(4,app.TrainnSpinner.Value) = app.wT4D4EditField.Value -app.L_dEditField.Value*(x_tri(app.TrainnSpinner.Value, 2) - app.t_ES(4,app.TrainnSpinner.Value)); 
            else
                y_inters(4,app.TrainnSpinner.Value)=0;
            end
            
            patch(app.UIAxes_4,(x_tri(app.TrainnSpinner.Value,:))',(y_tri(app.TrainnSpinner.Value,:))',[0.8 0.8 0.8], 'EdgeColor',[0.8 0.8 0.8]); % Triangle
            xline(app.UIAxes_4,app.t_ES(4,app.TrainnSpinner.Value),'m','LineWidth',2); % ES
            scatter(app.UIAxes_4,x_inters(4,app.TrainnSpinner.Value),y_inters(4,app.TrainnSpinner.Value),30,'k','o') % Intersection
            X_limit=x_tri(app.TrainnSpinner.Value, 2) + (app.PPT_abs(1,1) - app.PPT_abs(4,1) + app.base_rect(4,app.TrainnSpinner.Value));
            app.UIAxes_4.XLim = [-25+X_limit +25+X_limit];
            app.UIAxes_4.YLim = [0 1];
            
         %% 5   
            cla(app.UIAxes_5)
            app.TrainnSpinner.Value = app.TrainnSpinner.Value;

            x_tri(app.TrainnSpinner.Value, :) = [(app.PPT_abs(5,1) - app.wT5D5EditField.Value/app.L_dEditField.Value)-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(5,app.TrainnSpinner.Value), (app.PPT_abs(5,1))-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(5,app.TrainnSpinner.Value),  (app.PPT_abs(5,1))-app.tri_off(app.StepnSpinner.Value,1), (app.PPT_abs(5,1))-app.tri_off(app.StepnSpinner.Value,1)];
            y_tri(app.TrainnSpinner.Value, :) = [0, app.wT5D5EditField.Value, app.wT5D5EditField.Value, 0];
            
            x_inters(5,app.TrainnSpinner.Value) = app.t_ES(5,app.TrainnSpinner.Value);            
            if app.t_ES(5,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >= 1e-5 % a sx del rettangolo
                y_inters(5,app.TrainnSpinner.Value)=0;
            elseif and (app.t_ES(5,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)>0 , app.t_ES(5,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3)<0) %nel rettangolo
                y_inters(5,app.TrainnSpinner.Value)=app.wT5D5EditField.Value;
            elseif  and (app.t_ES(5,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) < 1e-5  , app.t_ES(5,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >=0 ) %se combacia proprio il momento di scatto prendi il vertice (condizione forzata senno a volte non mi segnalava il vertice)
                y_inters(5,app.TrainnSpinner.Value) = app.wT5D5EditField.Value;            
            elseif and ( app.t_ES(5,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2) < 0  , abs(app.t_ES(5,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)) - app.wT5D5EditField.Value/app.L_dEditField.Value < 0) % nel triangolo
                y_inters(5,app.TrainnSpinner.Value) = app.wT5D5EditField.Value -app.L_dEditField.Value*(x_tri(app.TrainnSpinner.Value, 2) - app.t_ES(5,app.TrainnSpinner.Value)); 
            else
                y_inters(5,app.TrainnSpinner.Value)=0;
            end
            
            
            patch(app.UIAxes_5,(x_tri(app.TrainnSpinner.Value,:))',(y_tri(app.TrainnSpinner.Value,:))',[0.8 0.8 0.8], 'EdgeColor',[0.8 0.8 0.8]); % v Triangle
            xline(app.UIAxes_5,app.t_ES(5,app.TrainnSpinner.Value),'c','LineWidth',2); % ES
            scatter(app.UIAxes_5,x_inters(5,app.TrainnSpinner.Value),y_inters(5,app.TrainnSpinner.Value),30,'k','o') % Intersection
            X_limit=x_tri(app.TrainnSpinner.Value, 2) + (app.PPT_abs(1,1) - app.PPT_abs(5,1) + app.base_rect(5,app.TrainnSpinner.Value)); 
            app.UIAxes_5.XLim = [-25+X_limit +25+X_limit];
            app.UIAxes_5.YLim = [0 1];

         %% 6   
            cla(app.UIAxes_6)
            app.TrainnSpinner.Value = app.TrainnSpinner.Value;

            x_tri(app.TrainnSpinner.Value, :) = [(app.PPT_abs(6,1) - app.wT6D6EditField.Value/app.L_dEditField.Value)-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(6,app.TrainnSpinner.Value), (app.PPT_abs(6,1))-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(6,app.TrainnSpinner.Value), (app.PPT_abs(6,1))-app.tri_off(app.StepnSpinner.Value,1), (app.PPT_abs(6,1))-app.tri_off(app.StepnSpinner.Value,1)];
            y_tri(app.TrainnSpinner.Value, :) = [0, app.wT6D6EditField.Value, app.wT6D6EditField.Value, 0];
            
             x_inters(6,app.TrainnSpinner.Value) = app.t_ES(6,app.TrainnSpinner.Value);
            if app.t_ES(6,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >= 1e-5 % a sx del rettangolo
                y_inters(6,app.TrainnSpinner.Value)=0;
            elseif and (app.t_ES(6,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)>0 , app.t_ES(6,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3)<0) %nel rettangolo
                y_inters(6,app.TrainnSpinner.Value)=app.wT6D6EditField.Value;
            elseif  and ( app.t_ES(6,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) < 1e-5 , app.t_ES(6,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >=0 )  %se combacia proprio il momento di scatto prendi il vertice (condizione forzata senno a volte non mi segnalava il vertice)
                y_inters(6,app.TrainnSpinner.Value) = app.wT6D6EditField.Value;            
            elseif and ( app.t_ES(6,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2) < 0  , abs(app.t_ES(6,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)) - app.wT6D6EditField.Value/app.L_dEditField.Value < 0) % nel triangolo
                y_inters(6,app.TrainnSpinner.Value) = app.wT6D6EditField.Value -app.L_dEditField.Value*(x_tri(app.TrainnSpinner.Value, 2) - app.t_ES(6,app.TrainnSpinner.Value)); 
            else
                y_inters(6,app.TrainnSpinner.Value)=0;
            end
            
            patch(app.UIAxes_6,(x_tri(app.TrainnSpinner.Value,:))',(y_tri(app.TrainnSpinner.Value,:))',[0.8 0.8 0.8], 'EdgeColor',[0.8 0.8 0.8]); % Triangle
            xline(app.UIAxes_6,app.t_ES(6,app.TrainnSpinner.Value),'r','LineWidth',2); % ES
            scatter(app.UIAxes_6,x_inters(6,app.TrainnSpinner.Value),y_inters(6,app.TrainnSpinner.Value),30,'k','o') % Intersection
            X_limit=x_tri(app.TrainnSpinner.Value, 2) + (app.PPT_abs(1,1) - app.PPT_abs(6,1) + app.base_rect(6,app.TrainnSpinner.Value));
            app.UIAxes_6.XLim = [-25+X_limit +25+X_limit];
            app.UIAxes_6.YLim = [0 1];
            
         %% 7   
            cla(app.UIAxes_7)
            app.TrainnSpinner.Value = app.TrainnSpinner.Value;

            x_tri(app.TrainnSpinner.Value, :) = [(app.PPT_abs(7,1)- app.wT7D7EditField.Value /app.L_dEditField.Value)-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(7,app.TrainnSpinner.Value), (app.PPT_abs(7,1))-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(7,app.TrainnSpinner.Value), (app.PPT_abs(7,1))-app.tri_off(app.StepnSpinner.Value,1), (app.PPT_abs(7,1))-app.tri_off(app.StepnSpinner.Value,1)];
            y_tri(app.TrainnSpinner.Value, :) = [0, app.wT7D7EditField.Value, app.wT7D7EditField.Value, 0];
            
            x_inters(7,app.TrainnSpinner.Value) = app.t_ES(7,app.TrainnSpinner.Value);
            if app.t_ES(7,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >= 1e-5 % a sx del rettangolo
                y_inters(7,app.TrainnSpinner.Value)=0;
            elseif and (app.t_ES(7,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)>0 , app.t_ES(7,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3)<0) %nel rettangolo
                y_inters(7,app.TrainnSpinner.Value)=app.wT7D7EditField.Value;
            elseif  and ( app.t_ES(7,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) < 1e-5 , app.t_ES(7,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >=0 )  %se combacia proprio il momento di scatto prendi il vertice (condizione forzata senno a volte non mi segnalava il vertice)
                y_inters(7,app.TrainnSpinner.Value) = app.wT7D7EditField.Value;            
            elseif and ( app.t_ES(7,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2) < 0  , abs(app.t_ES(7,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)) - app.wT7D7EditField.Value/app.L_dEditField.Value < 0) % nel triangolo
                y_inters(7,app.TrainnSpinner.Value) = app.wT7D7EditField.Value -app.L_dEditField.Value*(x_tri(app.TrainnSpinner.Value, 2) - app.t_ES(7,app.TrainnSpinner.Value)); 
            else
                y_inters(7,app.TrainnSpinner.Value)=0;
            end
            
            patch(app.UIAxes_7,(x_tri(app.TrainnSpinner.Value,:))',(y_tri(app.TrainnSpinner.Value,:))',[0.8 0.8 0.8], 'EdgeColor',[0.8 0.8 0.8]); % Triangle
            xline(app.UIAxes_7,app.t_ES(7,app.TrainnSpinner.Value),'g','LineWidth',2); % ES
            scatter(app.UIAxes_7,x_inters(7,app.TrainnSpinner.Value),y_inters(7,app.TrainnSpinner.Value),30,'k','o') % Intersection
            X_limit=x_tri(app.TrainnSpinner.Value, 2) + (app.PPT_abs(1,1) - app.PPT_abs(7,1)  + app.base_rect(7,app.TrainnSpinner.Value)); 
            app.UIAxes_7.XLim = [-25+X_limit +25+X_limit];
            app.UIAxes_7.YLim = [0 1];
            
         %% 8  
            cla(app.UIAxes_8)
            app.TrainnSpinner.Value = app.TrainnSpinner.Value;

            x_tri(app.TrainnSpinner.Value, :) = [(app.PPT_abs(8,1)- app.wT8D8EditField.Value /app.L_dEditField.Value)-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(8,app.TrainnSpinner.Value), (app.PPT_abs(8,1))-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(8,app.TrainnSpinner.Value), (app.PPT_abs(8,1))-app.tri_off(app.StepnSpinner.Value,1), (app.PPT_abs(8,1))-app.tri_off(app.StepnSpinner.Value,1)];
            y_tri(app.TrainnSpinner.Value, :) = [0, app.wT8D8EditField.Value, app.wT8D8EditField.Value, 0];
            
            x_inters(8,app.TrainnSpinner.Value) = app.t_ES(8,app.TrainnSpinner.Value);
            if app.t_ES(8,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >= 1e-5 % a sx del rettangolo
                y_inters(8,app.TrainnSpinner.Value)=0;
            elseif and (app.t_ES(8,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)>0 , app.t_ES(8,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3)<0) %nel rettangolo
                y_inters(8,app.TrainnSpinner.Value)=app.wT8D8EditField.Value;
            elseif  and ( app.t_ES(8,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) < 1e-5 , app.t_ES(8,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >=0 )   %se combacia proprio il momento di scatto prendi il vertice (condizione forzata senno a volte non mi segnalava il vertice)
                y_inters(8,app.TrainnSpinner.Value) = app.wT8D8EditField.Value;            
            elseif and ( app.t_ES(8,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2) < 0  , abs(app.t_ES(8,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)) - app.wT8D8EditField.Value/app.L_dEditField.Value < 0) % nel triangolo
                y_inters(8,app.TrainnSpinner.Value) = app.wT8D8EditField.Value -app.L_dEditField.Value*(x_tri(app.TrainnSpinner.Value, 2) - app.t_ES(8,app.TrainnSpinner.Value)); 
            else
                y_inters(8,app.TrainnSpinner.Value)=0;
            end
            
            patch(app.UIAxes_8,(x_tri(app.TrainnSpinner.Value,:))',(y_tri(app.TrainnSpinner.Value,:))',[0.8 0.8 0.8], 'EdgeColor',[0.8 0.8 0.8]); % Triangle
            xline(app.UIAxes_8,app.t_ES(8,app.TrainnSpinner.Value),'b','LineWidth',2); % ES
            scatter(app.UIAxes_8,x_inters(8,app.TrainnSpinner.Value),y_inters(8,app.TrainnSpinner.Value),30,'k','o') % Intersection
            X_limit=x_tri(app.TrainnSpinner.Value, 2) + (app.PPT_abs(1,1) - app.PPT_abs(8,1) + app.base_rect(8,app.TrainnSpinner.Value));
            app.UIAxes_8.XLim = [-25+X_limit +25+X_limit];
            app.UIAxes_8.YLim = [0 1];
            
         %% 9   
            cla(app.UIAxes_9)
            app.TrainnSpinner.Value = app.TrainnSpinner.Value;

            x_tri(app.TrainnSpinner.Value, :) = [(app.PPT_abs(9,1)- app.wT9D9EditField.Value /app.L_dEditField.Value)-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(9,app.TrainnSpinner.Value), (app.PPT_abs(9,1))-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(9,app.TrainnSpinner.Value), (app.PPT_abs(9,1))-app.tri_off(app.StepnSpinner.Value,1), (app.PPT_abs(9,1))-app.tri_off(app.StepnSpinner.Value,1)];
            y_tri(app.TrainnSpinner.Value, :) = [0, app.wT9D9EditField.Value, app.wT9D9EditField.Value, 0];
            
            x_inters(9,app.TrainnSpinner.Value) = app.t_ES(9,app.TrainnSpinner.Value);
            if app.t_ES(9,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >= 1e-5 % a sx del rettangolo
                y_inters(9,app.TrainnSpinner.Value)=0;
            elseif and (app.t_ES(9,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)>0 , app.t_ES(9,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3)<0) %nel rettangolo
                y_inters(9,app.TrainnSpinner.Value)=app.wT9D9EditField.Value;
            elseif  and ( app.t_ES(9,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) < 1e-5 , app.t_ES(9,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >=0 )  %se combacia proprio il momento di scatto prendi il vertice (condizione forzata senno a volte non mi segnalava il vertice)
                y_inters(9,app.TrainnSpinner.Value) = app.wT9D9EditField.Value;            
            elseif and ( app.t_ES(9,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2) < 0  , abs(app.t_ES(9,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)) - app.wT9D9EditField.Value/app.L_dEditField.Value < 0) % nel triangolo
                y_inters(9,app.TrainnSpinner.Value) = app.wT9D9EditField.Value -app.L_dEditField.Value*(x_tri(app.TrainnSpinner.Value, 2) - app.t_ES(9,app.TrainnSpinner.Value)); 
            else
                y_inters(9,app.TrainnSpinner.Value)=0;
            end
            
            patch(app.UIAxes_9,(x_tri(app.TrainnSpinner.Value,:))',(y_tri(app.TrainnSpinner.Value,:))',[0.8 0.8 0.8], 'EdgeColor',[0.8 0.8 0.8]); % Triangle
            xline(app.UIAxes_9,app.t_ES(9,app.TrainnSpinner.Value),'m','LineWidth',2); % ES
            scatter(app.UIAxes_9,x_inters(9,app.TrainnSpinner.Value),y_inters(9,app.TrainnSpinner.Value),30,'k','o') % Intersection
            X_limit=x_tri(app.TrainnSpinner.Value, 2) + (app.PPT_abs(1,1) - app.PPT_abs(9,1) + app.base_rect(9,app.TrainnSpinner.Value));
            app.UIAxes_9.XLim = [-25+X_limit +25+X_limit];
            app.UIAxes_9.YLim = [0 1];
            
         %% 10   
            cla(app.UIAxes_10)
            app.TrainnSpinner.Value = app.TrainnSpinner.Value;

            x_tri(app.TrainnSpinner.Value, :) = [(app.PPT_abs(10,1)- app.wT10D10EditField.Value /app.L_dEditField.Value)-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(10,app.TrainnSpinner.Value), (app.PPT_abs(10,1))-app.tri_off(app.StepnSpinner.Value,1)-app.base_rect(10,app.TrainnSpinner.Value), (app.PPT_abs(10,1))-app.tri_off(app.StepnSpinner.Value,1), (app.PPT_abs(10,1))-app.tri_off(app.StepnSpinner.Value,1)];
            y_tri(app.TrainnSpinner.Value, :) = [0, app.wT10D10EditField.Value, app.wT10D10EditField.Value, 0];
            
            x_inters(10,app.TrainnSpinner.Value) = app.t_ES(10,app.TrainnSpinner.Value);
            if app.t_ES(10,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >= 1e-5 % a sx del rettangolo
                y_inters(10,app.TrainnSpinner.Value)=0;
            elseif and (app.t_ES(10,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)>0 , app.t_ES(10,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3)<0) %nel rettangolo
                y_inters(10,app.TrainnSpinner.Value)=app.wT10D10EditField.Value;
            elseif  and ( app.t_ES(10,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) < 1e-5 , app.t_ES(10,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 3) >=0 )  %se combacia proprio il momento di scatto prendi il vertice (condizione forzata senno a volte non mi segnalava il vertice)
                y_inters(10,app.TrainnSpinner.Value) = app.wT10D10EditField.Value;            
            elseif and ( app.t_ES(10,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2) < 0  , abs(app.t_ES(10,app.TrainnSpinner.Value)-x_tri(app.TrainnSpinner.Value, 2)) - app.wT10D10EditField.Value/app.L_dEditField.Value < 0) % nel triangolo
                y_inters(10,app.TrainnSpinner.Value) = app.wT10D10EditField.Value -app.L_dEditField.Value*(x_tri(app.TrainnSpinner.Value, 2) - app.t_ES(10,app.TrainnSpinner.Value)); 
            else
                y_inters(10,app.TrainnSpinner.Value)=0;
            end
            
            patch(app.UIAxes_10,(x_tri(app.TrainnSpinner.Value,:))',(y_tri(app.TrainnSpinner.Value,:))',[0.8 0.8 0.8], 'EdgeColor',[0.8 0.8 0.8]); % Triangle
            xline(app.UIAxes_10,app.t_ES(10,app.TrainnSpinner.Value),'c','LineWidth',2); % ES
            scatter(app.UIAxes_10,x_inters(10,app.TrainnSpinner.Value),y_inters(10,app.TrainnSpinner.Value),30,'k','o') % Intersection
            X_limit=x_tri(app.TrainnSpinner.Value, 2) + (app.PPT_abs(1,1) - app.PPT_abs(10,1) + app.base_rect(10,app.TrainnSpinner.Value));
            app.UIAxes_10.XLim = [-25+X_limit +25+X_limit];
            app.UIAxes_10.YLim = [0 1];
            
         %% Esegui la somma e mostrala nella casella "S_p_current"
            app.Current_S_p = 0;
            for ii = 1:1:size(app.t_ES,1)
                app.Current_S_p = app.Current_S_p + y_inters (ii,app.TrainnSpinner.Value);
            end
            app.CurrentS_pEditField.Value = app.Current_S_p;
            
        %% Target summation graph
         
           t_arrivo_D(1,1)=1/(app.wD1ES1EditField.Value-1)+app.t_ES(1,app.TrainnSpinner.Value);   %Tempi di arrivo ordinati per branch
           t_arrivo_D(2,1)=1/(app.wD2ES2EditField.Value-1)+app.t_ES(2,app.TrainnSpinner.Value);
           t_arrivo_D(3,1)=1/(app.wD3ES3EditField.Value-1)+app.t_ES(3,app.TrainnSpinner.Value);
           t_arrivo_D(4,1)=1/(app.wD4ES4EditField.Value-1)+app.t_ES(4,app.TrainnSpinner.Value);
           t_arrivo_D(5,1)=1/(app.wD5ES5EditField.Value-1)+app.t_ES(5,app.TrainnSpinner.Value);
           t_arrivo_D(6,1)=1/(app.wD6ES6EditField.Value-1)+app.t_ES(6,app.TrainnSpinner.Value);
           t_arrivo_D(7,1)=1/(app.wD7ES7EditField.Value-1)+app.t_ES(7,app.TrainnSpinner.Value);
           t_arrivo_D(8,1)=1/(app.wD8ES8EditField.Value-1)+app.t_ES(8,app.TrainnSpinner.Value);
           t_arrivo_D(9,1)=1/(app.wD9ES9EditField.Value-1)+app.t_ES(9,app.TrainnSpinner.Value);
           t_arrivo_D(10,1)=1/(app.wD10ES10EditField.Value-1)+app.t_ES(10,app.TrainnSpinner.Value);
            
           %Ordinali per tempo di arrivo e preserva il loro num branch (I) in un vector a parte.
           [~,I] = sort(t_arrivo_D,1);
            
           % Costruisci i punti (e alla y ogni volta somma w_d e sottrai L_d*dt)
            
           X=[t_arrivo_D(I(1)), t_arrivo_D(I(1)), t_arrivo_D(I(2)), t_arrivo_D(I(2)), t_arrivo_D(I(3)), t_arrivo_D(I(3)),  t_arrivo_D(I(4)),t_arrivo_D(I(4)), t_arrivo_D(I(5)), t_arrivo_D(I(5)), t_arrivo_D(I(6)), t_arrivo_D(I(6)), t_arrivo_D(I(7)), t_arrivo_D(I(7)), t_arrivo_D(I(8)), t_arrivo_D(I(8)),  t_arrivo_D(I(9)),t_arrivo_D(I(9)), t_arrivo_D(I(10)), t_arrivo_D(I(10))];
           % vett Y
           Y(1)=0;
           
           Y(2)=app.w_T(I(1));
           Y(3)=Y(2)- app.L_dEditField.Value * (t_arrivo_D(I(2)) - t_arrivo_D(I(1)));
           if Y(3)<0
               Y(3)=0;
           end
           
           Y(4)=Y(3)+app.w_T(I(2));
           Y(5)=Y(4)- app.L_dEditField.Value * (t_arrivo_D(I(3)) - t_arrivo_D(I(2)));
           if Y(5)<0
               Y(5)=0;
           end
           
           Y(6)=Y(5)+app.w_T(I(3));
           Y(7)=Y(6)- app.L_dEditField.Value * (t_arrivo_D(I(4)) - t_arrivo_D(I(3)));
           if Y(7)<0
               Y(7)=0;
           end
           
           Y(8)=Y(7)+app.w_T(I(4));
           Y(9)=Y(8)- app.L_dEditField.Value * (t_arrivo_D(I(5)) - t_arrivo_D(I(4)));
           if Y(9)<0
               Y(9)=0;
           end
           
           Y(10)=Y(9)+app.w_T(I(5));
           Y(11)=Y(10)- app.L_dEditField.Value * (t_arrivo_D(I(6)) - t_arrivo_D(I(5)));
           if Y(11)<0
               Y(11)=0;
           end
           
           Y(12)=Y(11)+app.w_T(I(6));
           Y(13)=Y(12)- app.L_dEditField.Value * (t_arrivo_D(I(7)) - t_arrivo_D(I(6)));
           if Y(13)<0
               Y(13)=0;
           end  
           
           Y(14)=Y(13)+app.w_T(I(7));
           Y(15)=Y(14)- app.L_dEditField.Value * (t_arrivo_D(I(8)) - t_arrivo_D(I(7)));
           if Y(15)<0
               Y(15)=0;
           end
           
           Y(16)=Y(15)+app.w_T(I(8));
           Y(17)=Y(16)- app.L_dEditField.Value * (t_arrivo_D(I(9)) - t_arrivo_D(I(8)));
           if Y(17)<0
               Y(17)=0;
           end
           Y(18)=Y(17)+app.w_T(I(9));
           Y(19)=Y(18)- app.L_dEditField.Value * (t_arrivo_D(I(10)) - t_arrivo_D(I(9)));
           if Y(19)<0
               Y(19)=0;
           end
           
           Y(20)=Y(19)+app.w_T(I(10));

           
           plot(app.UIAxes,X,Y,'k');
           yline(app.UIAxes,app.S_thEditField.Value,'--r','LineWidth',1); % ES
           app.UIAxes.XLim = [5 50];         
         
        end

        % Value changed function: CurrentS_pEditField
        function CurrentS_pEditFieldValueChanged(app, event)
            %value = app.CurrentS_pEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TriangleChartPanel = uipanel(app.UIFigure);
            app.TriangleChartPanel.Title = 'Triangle Chart';
            app.UIAxes_1 = uiaxes(app.TriangleChartPanel);
            title(app.UIAxes_1, '')
            xlabel(app.UIAxes_1, '')
            ylabel(app.UIAxes_1, '')
            app.UIAxes_1.XDir = 'reverse';
            app.UIAxes_1.XTick = [-35 -34 -33 -32 -31 -30 -29 -28 -27 -26 -25 -24 -23 -22 -21 -20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55];
            app.UIAxes_1.XTickLabel = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; '0'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UIAxes_1.YTick = [0 1];
            app.UIAxes_2 = uiaxes(app.TriangleChartPanel);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XDir = 'reverse';
            app.UIAxes_2.XTick = [-35 -34 -33 -32 -31 -30 -29 -28 -27 -26 -25 -24 -23 -22 -21 -20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55];
            app.UIAxes_2.XTickLabel = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; '0'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UIAxes_2.YTick = [0 1];
            app.UIAxes_3 = uiaxes(app.TriangleChartPanel);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.XDir = 'reverse';
            app.UIAxes_3.XTick = [-35 -34 -33 -32 -31 -30 -29 -28 -27 -26 -25 -24 -23 -22 -21 -20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55];
            app.UIAxes_3.XTickLabel = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; '0'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UIAxes_3.YTick = [0 1];
            app.UIAxes_4 = uiaxes(app.TriangleChartPanel);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            app.UIAxes_4.XDir = 'reverse';
            app.UIAxes_4.XTick = [-35 -34 -33 -32 -31 -30 -29 -28 -27 -26 -25 -24 -23 -22 -21 -20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55];
            app.UIAxes_4.XTickLabel = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; '0'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UIAxes_4.YTick = [0 1];
            app.UIAxes_5 = uiaxes(app.TriangleChartPanel);
            title(app.UIAxes_5, '')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, '')
            app.UIAxes_5.XDir = 'reverse';
            app.UIAxes_5.XTick = [-35 -34 -33 -32 -31 -30 -29 -28 -27 -26 -25 -24 -23 -22 -21 -20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55];
            app.UIAxes_5.XTickLabel = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; '0'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UIAxes_5.YTick = [0 1];
            app.UIAxes_6 = uiaxes(app.TriangleChartPanel);
            title(app.UIAxes_6, '')
            xlabel(app.UIAxes_6, '')
            ylabel(app.UIAxes_6, '')
            app.UIAxes_6.XDir = 'reverse';
            app.UIAxes_6.XTick = [-35 -34 -33 -32 -31 -30 -29 -28 -27 -26 -25 -24 -23 -22 -21 -20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55];
            app.UIAxes_6.XTickLabel = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; '0'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UIAxes_6.YTick = [0 1];
            app.UIAxes_7 = uiaxes(app.TriangleChartPanel);
            title(app.UIAxes_7, '')
            xlabel(app.UIAxes_7, '')
            ylabel(app.UIAxes_7, '')
            app.UIAxes_7.XDir = 'reverse';
            app.UIAxes_7.XTick = [-35 -34 -33 -32 -31 -30 -29 -28 -27 -26 -25 -24 -23 -22 -21 -20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55];
            app.UIAxes_7.XTickLabel = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; '0'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UIAxes_7.YTick = [0 1];
            app.UIAxes_8 = uiaxes(app.TriangleChartPanel);
            title(app.UIAxes_8, '')
            xlabel(app.UIAxes_8, '')
            ylabel(app.UIAxes_8, '')
            app.UIAxes_8.XDir = 'reverse';
            app.UIAxes_8.XTick = [-35 -34 -33 -32 -31 -30 -29 -28 -27 -26 -25 -24 -23 -22 -21 -20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55];
            app.UIAxes_8.XTickLabel = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; '0'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UIAxes_8.YTick = [0 1];
            app.UIAxes_8.YTickLabel = {'0'; '1'};
            app.UIAxes_9 = uiaxes(app.TriangleChartPanel);
            title(app.UIAxes_9, '')
            xlabel(app.UIAxes_9, '')
            ylabel(app.UIAxes_9, '')
            app.UIAxes_9.XDir = 'reverse';
            app.UIAxes_9.XTick = [-35 -34 -33 -32 -31 -30 -29 -28 -27 -26 -25 -24 -23 -22 -21 -20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55];
            app.UIAxes_9.XTickLabel = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; '0'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UIAxes_9.YTick = [0 1];
            app.UIAxes_10 = uiaxes(app.TriangleChartPanel);
            title(app.UIAxes_10, '')
            xlabel(app.UIAxes_10, 'reversed time (ms)')
            ylabel(app.UIAxes_10, '')
            app.UIAxes_10.XDir = 'reverse';
            app.UIAxes_10.XTick = [-35 -34 -33 -32 -31 -30 -29 -28 -27 -26 -25 -24 -23 -22 -21 -20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55];
            app.UIAxes_10.XTickLabel = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; '0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'; '51'; '52'; '53'; '54'; '55'};
            app.UIAxes_10.YTick = [0 1];
            app.UIAxes_10.YTickLabel = {'0'; '1'};
            app.Label_Leg = uilabel(app.TriangleChartPanel);
            app.Label_Leg.Text = {'Legenda:'; '- The nMNSD is represented with the set of gray-shaded triangles'; '- External spikes are represented in colors. I_ref is signaled with the activation of the'; ' green circle on the right'};
            app.ESLabel_1 = uilabel(app.TriangleChartPanel);
            app.ESLabel_1.Text = 'Br.1';
            app.ESLabel_2 = uilabel(app.TriangleChartPanel);
            app.ESLabel_2.Text = 'Br.2';
            app.ESLabel_3 = uilabel(app.TriangleChartPanel);
            app.ESLabel_3.Text = 'Br.3';
            app.ESLabel_4 = uilabel(app.TriangleChartPanel);
            app.ESLabel_4.Text = 'Br.4';
            app.ESLabel_5 = uilabel(app.TriangleChartPanel);
            app.ESLabel_5.Text = 'Br 5';
            app.ESLabel_6 = uilabel(app.TriangleChartPanel);
            app.ESLabel_6.Text = 'Br.6';
            app.ESLabel_7 = uilabel(app.TriangleChartPanel);
            app.ESLabel_7.Text = 'Br.7';
            app.ESLabel_8 = uilabel(app.TriangleChartPanel);
            app.ESLabel_8.Text = 'Br.8';
            app.ESLabel_9 = uilabel(app.TriangleChartPanel);
            app.ESLabel_9.Text = 'Br.9';
            app.ESLabel_10 = uilabel(app.TriangleChartPanel);
            app.ESLabel_10.Text = 'Br.10';
            app.Lamp1 = uilamp(app.TriangleChartPanel);
            app.Lamp1.Enable = 'off';
            app.Lamp2 = uilamp(app.TriangleChartPanel);
            app.Lamp2.Enable = 'off';
            app.Lamp3 = uilamp(app.TriangleChartPanel);
            app.Lamp3.Enable = 'off';
            app.Lamp4 = uilamp(app.TriangleChartPanel);
            app.Lamp4.Enable = 'off';
            app.Lamp5 = uilamp(app.TriangleChartPanel);
            app.Lamp5.Enable = 'off';
            app.Lamp6 = uilamp(app.TriangleChartPanel);
            app.Lamp6.Enable = 'off';
            app.Lamp7 = uilamp(app.TriangleChartPanel);
            app.Lamp7.Enable = 'off';
            app.Lamp8 = uilamp(app.TriangleChartPanel);
            app.Lamp8.Enable = 'off';
            app.Lamp9 = uilamp(app.TriangleChartPanel);
            app.Lamp9.Enable = 'off';
            app.Lamp10 = uilamp(app.TriangleChartPanel);
            app.Lamp10.Enable = 'off';
            app.CurrentS_pLabel = uilabel(app.TriangleChartPanel);
            app.CurrentS_pLabel.Text = 'Current S_p:';
            app.CurrentS_pEditField = uieditfield(app.TriangleChartPanel, 'numeric');
            app.CurrentS_pEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentS_pEditFieldValueChanged, true);
            app.StructureparametersPanel = uipanel(app.UIFigure);
            app.StructureparametersPanel.Title = 'Structure parameters';
            app.wD1ES1EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wD1ES1EditFieldLabel.Text = 'w (D1,ES1)';
            app.wD1ES1EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wD2ES2EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wD2ES2EditFieldLabel.Text = 'w (D2,ES2)';
            app.wD2ES2EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wD3ES3EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wD3ES3EditFieldLabel.Text = 'w (D3,ES3)';
            app.wD3ES3EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wD4ES4EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wD4ES4EditFieldLabel.Text = 'w (D4,ES4)';
            app.wD4ES4EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wD5ES5EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wD5ES5EditFieldLabel.Text = 'w (D5,ES5)';
            app.wD5ES5EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wD6ES6EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wD6ES6EditFieldLabel.Text = 'w (D6,ES6)';
            app.wD6ES6EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wD7ES7EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wD7ES7EditFieldLabel.Text = 'w (D7,ES7)';
            app.wD7ES7EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wD8ES8EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wD8ES8EditFieldLabel.Text = 'w (D8,ES8)';
            app.wD8ES8EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wD9ES9EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wD9ES9EditFieldLabel.Text = 'w (D9,ES9)';
            app.wD9ES9EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wD10ES10EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wD10ES10EditFieldLabel.Text = 'w (D10,ES10)';
            app.wD10ES10EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.S_thEditFieldLabel = uilabel(app.StructureparametersPanel);
            app.S_thEditFieldLabel.Text = 'S_th';
            app.S_thEditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.L_dEditFieldLabel = uilabel(app.StructureparametersPanel);
            app.L_dEditFieldLabel.Text = 'L_d';
            app.L_dEditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wT1D1EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wT1D1EditFieldLabel.Text = 'w (T1,D1)';
            app.wT1D1EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wT2D2EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wT2D2EditFieldLabel.Text = 'w (T2,D2)';
            app.wT2D2EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wT3D3EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wT3D3EditFieldLabel.Text = 'w (T3,D3)';
            app.wT3D3EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wT4D4EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wT4D4EditFieldLabel.Text = 'w (T4,D4)';
            app.wT4D4EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wT5D5EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wT5D5EditFieldLabel.Text = 'w (T5,D5)';
            app.wT5D5EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wT6D6EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wT6D6EditFieldLabel.Text = 'w (T6,D6)';
            app.wT6D6EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wT7D7EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wT7D7EditFieldLabel.Text = 'w (T7,D7)';
            app.wT7D7EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wT8D8EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wT8D8EditFieldLabel.Text = 'w (T8,D8)';
            app.wT8D8EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wT9D9EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wT9D9EditFieldLabel.Text = 'w (T9,D9)';
            app.wT9D9EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.wT10D10EditFieldLabel = uilabel(app.StructureparametersPanel);
            app.wT10D10EditFieldLabel.Text = 'w (T1,0D10)';
            app.wT10D10EditField = uieditfield(app.StructureparametersPanel, 'numeric');
            app.TargetsummationPanel = uipanel(app.UIFigure);
            app.TargetsummationPanel.Title = 'Target summation';
            app.SpikegentimeEditFieldLabel = uilabel(app.TargetsummationPanel);
            app.SpikegentimeEditFieldLabel.Text = 'Spike gen. time';
            app.SpikegentimeEditField = uieditfield(app.TargetsummationPanel, 'numeric');
            app.S_pMEditFieldLabel = uilabel(app.TargetsummationPanel);
            app.S_pMEditFieldLabel.Text = 'S_pM';
            app.S_pMEditField = uieditfield(app.TargetsummationPanel, 'numeric');
            app.UIAxes = uiaxes(app.TargetsummationPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, 'S_T')
            app.UIAxes.YLim = [0 1.6];
            app.UIAxes.YTick = [0 0.5 1 1.5];
            app.UIAxes.YTickLabel = {'0'; '0.5'; '1'; '1.5'};
            app.Label_Leg_2 = uilabel(app.TargetsummationPanel);
            app.Label_Leg_2.Text = {'Legenda:'; '- S_th is represented by the red dashed line'};
            app.LoadingandcontrolPanel = uipanel(app.UIFigure);
            app.LoadingandcontrolPanel.Title = 'Loading and control';
            app.Panel = uipanel(app.LoadingandcontrolPanel);
            app.LoadnMNSDparametersButton = uibutton(app.Panel, 'state');
            app.LoadnMNSDparametersButton.ValueChangedFcn = createCallbackFcn(app, @LoadnMNSDparametersButtonValueChanged, true);
            app.LoadnMNSDparametersButton.Text = 'Load nMNSD parameters';
            app.ResetinputweightsButton = uibutton(app.Panel, 'state');
            app.ResetinputweightsButton.ValueChangedFcn = createCallbackFcn(app, @ResetinputweightsButtonValueChanged, true);
            app.ResetinputweightsButton.Enable = 'off';
            app.ResetinputweightsButton.Text = 'Reset input weights';
            app.Panel_2 = uipanel(app.LoadingandcontrolPanel);
            app.LoadthesetofparallelspiketrainsButton = uibutton(app.Panel_2, 'state');
            app.LoadthesetofparallelspiketrainsButton.ValueChangedFcn = createCallbackFcn(app, @LoadthesetofparallelspiketrainsButtonValueChanged, true);
            app.LoadthesetofparallelspiketrainsButton.Text = 'Load the set of parallel spike trains';
            app.TrainnSpinnerLabel = uilabel(app.Panel_2);
            app.TrainnSpinnerLabel.Text = 'Train n.';
            app.TrainnSpinner = uispinner(app.Panel_2);
            app.TrainnSpinner.ValueChangedFcn = createCallbackFcn(app, @TrainnSpinnerValueChanged, true);
            app.StepnSpinnerLabel = uilabel(app.Panel_2);
            app.StepnSpinnerLabel.Text = 'Step n.';
            app.StepnSpinner = uispinner(app.Panel_2);
            app.StepnSpinner.ValueChangedFcn = createCallbackFcn(app, @StepnSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nMNSD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end